classdef Cal_spectra_input < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        xp_calSpecparameterssettingLabel  matlab.ui.control.Label
        OKButton                       matlab.ui.control.Button
        time_rangeEditField_2Label     matlab.ui.control.Label
        time_rangeEditField_2          matlab.ui.control.EditField
        limited_freqEditField_2Label   matlab.ui.control.Label
        limited_freqEditField_2        matlab.ui.control.EditField
        select_chanEditField_2Label    matlab.ui.control.Label
        select_chanEditField_2         matlab.ui.control.EditField
        freq_resolutionEditFieldLabel  matlab.ui.control.Label
        freq_resolutionEditField       matlab.ui.control.EditField
        window_sizeEditField_2Label    matlab.ui.control.Label
        window_sizeEditField_2         matlab.ui.control.EditField
        overlappingEditField_2Label    matlab.ui.control.Label
        overlappingEditField_2         matlab.ui.control.EditField
        Label                          matlab.ui.control.Label
        HzLabel                        matlab.ui.control.Label
        HzLabel_2                      matlab.ui.control.Label
        sLabel                         matlab.ui.control.Label
        sLabel_2                       matlab.ui.control.Label
    end

    
    properties (Access = public)
        app_parent % parent app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app_parent)
            app.app_parent = app_parent;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % 将所有参数标准化  返回main界面
            time_range = app.time_rangeEditField_2.Value;
            select_chan = app.select_chanEditField_2.Value;
            window_size = app.window_sizeEditField_2.Value;
            overlapping = app.overlappingEditField_2.Value;
            freq_resolution = app.freq_resolutionEditField.Value;
            limited_freq = app.limited_freqEditField_2.Value;
            
            eval(['time_range_V = ',time_range])
            if strcmp(select_chan,"all")
                select_chan_V = 1:app.app_parent.XiPi.nbchan;
            else
                eval(['select_chan_V = ',select_chan])
            end
            eval(['window_size_V = ',window_size])
            eval(['overlapping_V = ',overlapping])
            eval(['freq_resolution_V = ',freq_resolution])
            eval(['limited_freq_V = ',limited_freq])
            srate = app.app_parent.XiPi.srate;
            % 计算,修改状态
            app.app_parent.XiPi = xp_calculateSpec(app.app_parent.XiPi,time_range_V,limited_freq_V,'select_chan',select_chan_V,'window_size',window_size_V * srate,'overlapping',srate * overlapping_V / 100,'freqResolution',freq_resolution_V);
            app.app_parent.passLamp_2.Color = "g";
            
            % 关闭
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Tag = 'calSpec_paras';
            app.xp_calSpecparameterssettingLabel = uilabel(app.UIFigure);
            app.xp_calSpecparameterssettingLabel.Text = '  xp_calSpec( ) - parameters setting';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.time_rangeEditField_2Label = uilabel(app.UIFigure);
            app.time_rangeEditField_2Label.Text = 'time_range';
            app.time_rangeEditField_2 = uieditfield(app.UIFigure, 'text');
            app.time_rangeEditField_2.Value = '[1 30]';
            app.limited_freqEditField_2Label = uilabel(app.UIFigure);
            app.limited_freqEditField_2Label.Text = 'limited_freq';
            app.limited_freqEditField_2 = uieditfield(app.UIFigure, 'text');
            app.limited_freqEditField_2.Value = '50';
            app.select_chanEditField_2Label = uilabel(app.UIFigure);
            app.select_chanEditField_2Label.Text = 'select_chan';
            app.select_chanEditField_2 = uieditfield(app.UIFigure, 'text');
            app.select_chanEditField_2.Value = 'all';
            app.freq_resolutionEditFieldLabel = uilabel(app.UIFigure);
            app.freq_resolutionEditFieldLabel.Text = 'freq_resolution';
            app.freq_resolutionEditField = uieditfield(app.UIFigure, 'text');
            app.freq_resolutionEditField.Value = '0.5';
            app.window_sizeEditField_2Label = uilabel(app.UIFigure);
            app.window_sizeEditField_2Label.Text = 'window_size';
            app.window_sizeEditField_2 = uieditfield(app.UIFigure, 'text');
            app.window_sizeEditField_2.Value = '1';
            app.overlappingEditField_2Label = uilabel(app.UIFigure);
            app.overlappingEditField_2Label.Text = 'overlapping';
            app.overlappingEditField_2 = uieditfield(app.UIFigure, 'text');
            app.overlappingEditField_2.Value = '50';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = '(Hz)';
            app.HzLabel_2 = uilabel(app.UIFigure);
            app.HzLabel_2.Text = '(Hz)';
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = '(s)';
            app.sLabel_2 = uilabel(app.UIFigure);
            app.sLabel_2.Text = '(s)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cal_spectra_input(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end