classdef Sep_spectra_input < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        xp_separateSpecparameterssettingLabel  matlab.ui.control.Label
        OKButton                      matlab.ui.control.Button
        select_channelEditFieldLabel  matlab.ui.control.Label
        select_channelEditField       matlab.ui.control.EditField
        separateSalceEditFieldLabel   matlab.ui.control.Label
        separateSalceEditField        matlab.ui.control.EditField
    end

    
    properties (Access = public)
        app_parent % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app_parent)
            app.app_parent = app_parent;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            select_chan = app.select_channelEditField.Value;
            separate_scale = app.separateSalceEditField.Value;
            if strcmp(select_chan,"all")
                select_chan_V = 1:app.app_parent.XiPi.nbchan;
            else
                eval(['select_chan_V = ',select_chan])
            end
            app.app_parent.XiPi = xp_separateSepc(app.app_parent.XiPi,'chooseChannels',select_chan_V,'scale',separate_scale);
            
            app.app_parent.passLamp_3.Color = "g";
            app.app_parent.seperatescaleTextArea.Value = app.app_parent.XiPi.separateSalce;
            
            % 设置app.parent列表
            app.app_parent.ChannelListBox.Items = fieldnames(app.app_parent.XiPi.separate.pi);
            
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.xp_separateSpecparameterssettingLabel = uilabel(app.UIFigure);
            app.xp_separateSpecparameterssettingLabel.Text = '  xp_separateSpec( ) - parameters setting';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.select_channelEditFieldLabel = uilabel(app.UIFigure);
            app.select_channelEditFieldLabel.Text = 'select_channel';
            app.select_channelEditField = uieditfield(app.UIFigure, 'text');
            app.select_channelEditField.Value = 'all';
            app.separateSalceEditFieldLabel = uilabel(app.UIFigure);
            app.separateSalceEditFieldLabel.Text = 'separateSalce';
            app.separateSalceEditField = uieditfield(app.UIFigure, 'text');
            app.separateSalceEditField.Value = 'natural';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sep_spectra_input(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end