classdef xp_designer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        PushTool                        matlab.ui.container.toolbar.PushTool
        ChannelListBoxLabel             matlab.ui.control.Label
        ChannelListBox                  matlab.ui.control.ListBox
        ImportdataButton                matlab.ui.control.Button
        filenameTextAreaLabel           matlab.ui.control.Label
        filenameTextArea                matlab.ui.control.TextArea
        channelnumberTextAreaLabel      matlab.ui.control.Label
        channelnumberTextArea           matlab.ui.control.TextArea
        srateTextAreaLabel              matlab.ui.control.Label
        srateTextArea                   matlab.ui.control.TextArea
        seperatescaleTextAreaLabel      matlab.ui.control.Label
        seperatescaleTextArea           matlab.ui.control.TextArea
        parameterizescaleTextAreaLabel  matlab.ui.control.Label
        parameterizescaleTextArea       matlab.ui.control.TextArea
        passLamp                        matlab.ui.control.Lamp
        Cal_SpectraButton               matlab.ui.control.Button
        passLamp_2                      matlab.ui.control.Lamp
        Run_XiPiButton                  matlab.ui.control.Button
        passLamp_3                      matlab.ui.control.Lamp
        ParameterizeButton              matlab.ui.control.Button
        passLamp_4                      matlab.ui.control.Lamp
        Step1Label                      matlab.ui.control.Label
        Step4Label                      matlab.ui.control.Label
        Step3Label                      matlab.ui.control.Label
        Step2Label                      matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Cal_spectra_app
        Sep_spectra_app
        Parameterize_app
    end
    
    properties (Access = public)
        XiPi
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportdataButton
        function ImportdataButtonPushed(app, event)
            app.XiPi = xp_importdata([]);
            app.filenameTextArea.Value = app.XiPi.filename;
            app.channelnumberTextArea.Value = num2str(app.XiPi.nbchan);
            app.srateTextArea.Value = strcat(num2str(app.XiPi.srate),"Hz");
            app.passLamp.Color = "g";
            app.passLamp_2.Color = "r";
            app.passLamp_3.Color = "r";
            app.passLamp_4.Color = "r";
        end

        % Value changed function: filenameTextArea
        function filenameTextAreaValueChanged(app, event)
            
        end

        % Button pushed function: Cal_SpectraButton
        function Cal_SpectraButtonPushed(app, event)
            app.Cal_spectra_app = Cal_spectra_input(app);
            
        end

        % Button pushed function: Run_XiPiButton
        function Run_XiPiButtonPushed(app, event)
            app.Sep_spectra_app = Sep_spectra_input(app);
            
        end

        % Button pushed function: ParameterizeButton
        function ParameterizeButtonPushed(app, event)
            app.Parameterize_app = Parameterize_input(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Tag = 'main';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.ChannelListBoxLabel = uilabel(app.UIFigure);
            app.ChannelListBoxLabel.Text = 'Channel';
            app.ChannelListBox = uilistbox(app.UIFigure);
            app.ChannelListBox.Items = {};
            app.ChannelListBox.Value = {};
            app.ImportdataButton = uibutton(app.UIFigure, 'push');
            app.ImportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportdataButtonPushed, true);
            app.ImportdataButton.Icon = 'import.png';
            app.ImportdataButton.Text = 'Import data  ';
            app.filenameTextAreaLabel = uilabel(app.UIFigure);
            app.filenameTextAreaLabel.Text = 'filename';
            app.filenameTextArea = uitextarea(app.UIFigure);
            app.filenameTextArea.ValueChangedFcn = createCallbackFcn(app, @filenameTextAreaValueChanged, true);
            app.filenameTextArea.Editable = 'off';
            app.filenameTextArea.Value = {'loading..'};
            app.channelnumberTextAreaLabel = uilabel(app.UIFigure);
            app.channelnumberTextAreaLabel.Text = 'channel number';
            app.channelnumberTextArea = uitextarea(app.UIFigure);
            app.channelnumberTextArea.Editable = 'off';
            app.channelnumberTextArea.Value = {'loading...'};
            app.srateTextAreaLabel = uilabel(app.UIFigure);
            app.srateTextAreaLabel.Text = 'srate';
            app.srateTextArea = uitextarea(app.UIFigure);
            app.srateTextArea.Editable = 'off';
            app.srateTextArea.Value = {'loading...'};
            app.seperatescaleTextAreaLabel = uilabel(app.UIFigure);
            app.seperatescaleTextAreaLabel.Text = 'seperate scale';
            app.seperatescaleTextArea = uitextarea(app.UIFigure);
            app.seperatescaleTextArea.Editable = 'off';
            app.seperatescaleTextArea.Value = {'loading...'};
            app.parameterizescaleTextAreaLabel = uilabel(app.UIFigure);
            app.parameterizescaleTextAreaLabel.Text = 'parameterize scale';
            app.parameterizescaleTextArea = uitextarea(app.UIFigure);
            app.parameterizescaleTextArea.Editable = 'off';
            app.parameterizescaleTextArea.Value = {'loading...'};
            app.passLamp = uilamp(app.UIFigure);
            app.Cal_SpectraButton = uibutton(app.UIFigure, 'push');
            app.Cal_SpectraButton.ButtonPushedFcn = createCallbackFcn(app, @Cal_SpectraButtonPushed, true);
            app.Cal_SpectraButton.Icon = 'spec.png';
            app.Cal_SpectraButton.Text = 'Cal_Spectra';
            app.passLamp_2 = uilamp(app.UIFigure);
            app.Run_XiPiButton = uibutton(app.UIFigure, 'push');
            app.Run_XiPiButton.ButtonPushedFcn = createCallbackFcn(app, @Run_XiPiButtonPushed, true);
            app.Run_XiPiButton.Icon = 'star.png';
            app.Run_XiPiButton.Text = 'Run_XiPi     ';
            app.passLamp_3 = uilamp(app.UIFigure);
            app.ParameterizeButton = uibutton(app.UIFigure, 'push');
            app.ParameterizeButton.ButtonPushedFcn = createCallbackFcn(app, @ParameterizeButtonPushed, true);
            app.ParameterizeButton.Icon = 'para.png';
            app.ParameterizeButton.Text = 'Parameterize';
            app.passLamp_4 = uilamp(app.UIFigure);
            app.Step1Label = uilabel(app.UIFigure);
            app.Step1Label.Text = 'Step 1 ';
            app.Step4Label = uilabel(app.UIFigure);
            app.Step4Label.Text = 'Step 4';
            app.Step3Label = uilabel(app.UIFigure);
            app.Step3Label.Text = 'Step 3 ';
            app.Step2Label = uilabel(app.UIFigure);
            app.Step2Label.Text = 'Step 2 ';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = xp_designer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end